classdef example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputPanel            matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        AEditFieldLabel       matlab.ui.control.Label
        AEditField            matlab.ui.control.EditField
        BEditFieldLabel       matlab.ui.control.Label
        BEditField            matlab.ui.control.EditField
        x0EditFieldLabel      matlab.ui.control.Label
        x0EditField           matlab.ui.control.EditField
        t1EditFieldLabel      matlab.ui.control.Label
        t1EditField           matlab.ui.control.EditField
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        GridLayout6           matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        ResultLabel           matlab.ui.control.Label
        Tab_2                 matlab.ui.container.Tab
        GridLayout3           matlab.ui.container.GridLayout
        WcLabel               matlab.ui.control.Label
        WcTextArea            matlab.ui.control.TextArea
        uLabel                matlab.ui.control.Label
        uTextArea             matlab.ui.control.TextArea
        PBHTab                matlab.ui.container.Tab
        GridLayout5           matlab.ui.container.GridLayout
        lambdaEditFieldLabel  matlab.ui.control.Label
        lambdaEditField       matlab.ui.control.EditField
        PBHEditFieldLabel     matlab.ui.control.Label
        PBHEditField          matlab.ui.control.EditField
        QcLabel               matlab.ui.control.Label
        QcEditField           matlab.ui.control.EditField
        nEditFieldLabel       matlab.ui.control.Label
        nEditField            matlab.ui.control.EditField
        Button                matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            A = app.AEditField.Value;
            B = app.BEditField.Value;
            t1 = app.t1EditField.Value;
            x0 = app.x0EditField.Value;
            
            A = str2num(A);
            B = str2num(B);
            t1 = str2num(t1);
            x0 = str2num(x0);
            
            %             eval(['A=',char(A),';']);
            %             eval(['B=',char(B),';']);
            Qc=ctrb(A,B);
            nc=rank(Qc);
            if nc==rank(A)
                disp("可控");
                app.ResultLabel.Text = "可控"
            else
                disp("不可控");
                app.ResultLabel.Text = "不可控"
            end
            lambda = eig(A);
            [n,m] = size(A);
            sum = 0;
            app.lambdaEditField.Value = mat2str(lambda);
            for i = 1:length(lambda)
                if rank([A-lambda(i)*eye(n),B])==n
                    sum = sum +1;
                    if i==1
                        app.PBHEditField.Value = mat2str([A-lambda(i)*eye(n),B])
                        app.nEditField.Value = num2str(rank([A-lambda(i)*eye(n),B]))
                    else
                        app.PBHEditField.Value = app.PBHEditField.Value + " , " + mat2str([A-lambda(i)*eye(n),B])
                        app.nEditField.Value = app.nEditField.Value + " , " + num2str(rank([A-lambda(i)*eye(n),B]))
                    end
                    
                end
            end
            
            app.QcEditField.Value = mat2str(Qc);
            
            %% 格拉姆矩阵判据
            syms t
            
            %% 求Wc
            Wc=int( expm(A*t)*B*B'*expm(A'*t) ,0,t1)
            
            %% 求u
            u=( -B'* expm(A'*(t1-t))/Wc ) * expm(A*t1)*x0
            
            %% 绘图
            x = linspace(0,t1)
            y = char(u)
            y = strrep(y,"*",".*")
            y = strrep(y,"t","x")
            
            app.uTextArea.Value = y;
            app.WcTextArea.Value = char(Wc);
            %% 转化输入
            eval(['y=',char(y),';']);
            %% 绘图
            
            plot(app.UIAxes,x,y,'LineWidth',3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.GridLayout = uigridlayout(app.InputPanel);
            app.AEditFieldLabel = uilabel(app.GridLayout);
            app.AEditFieldLabel.Text = 'A矩阵';
            app.AEditField = uieditfield(app.GridLayout, 'text');
            app.AEditField.Value = '-0.5 0;0 -1';
            app.BEditFieldLabel = uilabel(app.GridLayout);
            app.BEditFieldLabel.Text = 'B矩阵';
            app.BEditField = uieditfield(app.GridLayout, 'text');
            app.BEditField.Value = '0.5;1';
            app.x0EditFieldLabel = uilabel(app.GridLayout);
            app.x0EditFieldLabel.Text = 'x0矩阵';
            app.x0EditField = uieditfield(app.GridLayout, 'text');
            app.x0EditField.Value = '10;-1';
            app.t1EditFieldLabel = uilabel(app.GridLayout);
            app.t1EditFieldLabel.Text = '时间t1';
            app.t1EditField = uieditfield(app.GridLayout, 'text');
            app.t1EditField.Value = '2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '绘图';
            app.GridLayout6 = uigridlayout(app.Tab);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'u的变化曲线')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.ResultLabel = uilabel(app.GridLayout6);
            app.ResultLabel.Text = 'Result';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '格拉姆判据';
            app.GridLayout3 = uigridlayout(app.Tab_2);
            app.WcLabel = uilabel(app.GridLayout3);
            app.WcLabel.Text = {'格拉姆'; '矩阵Wc'};
            app.WcTextArea = uitextarea(app.GridLayout3);
            app.uLabel = uilabel(app.GridLayout3);
            app.uLabel.Text = {'构造'; '矩阵u'};
            app.uTextArea = uitextarea(app.GridLayout3);
            app.PBHTab = uitab(app.TabGroup);
            app.PBHTab.Title = 'PBH判据';
            app.GridLayout5 = uigridlayout(app.PBHTab);
            app.lambdaEditFieldLabel = uilabel(app.GridLayout5);
            app.lambdaEditFieldLabel.Text = '特征值lambda';
            app.lambdaEditField = uieditfield(app.GridLayout5, 'text');
            app.PBHEditFieldLabel = uilabel(app.GridLayout5);
            app.PBHEditFieldLabel.Text = 'PBH矩阵';
            app.PBHEditField = uieditfield(app.GridLayout5, 'text');
            app.QcLabel = uilabel(app.GridLayout5);
            app.QcLabel.Text = {'秩判据'; 'Qc'};
            app.QcEditField = uieditfield(app.GridLayout5, 'text');
            app.nEditFieldLabel = uilabel(app.GridLayout5);
            app.nEditFieldLabel.Text = '矩阵阶数n';
            app.nEditField = uieditfield(app.GridLayout5, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '判别';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '可控性判别';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end